classdef finalproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnteraCountyLabel             matlab.ui.control.Label
        EnteraCountyEditField         matlab.ui.control.EditField
        UITable                       matlab.ui.control.Table
        GraphYourSelectedCountysAQIandtheNationalAQIButton  matlab.ui.control.Button
        GraphYourChosenCountysPercentGoodModerateBadDaysButton  matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
        SelectYearLabel               matlab.ui.control.Label
        SelectYearDropDown            matlab.ui.control.DropDown
        GetInformationButton          matlab.ui.control.Button
        AQIandPercentofGoodDaysModerateDaysandBadDaysbyCountyLabel  matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        AnAQIabove100isunhealthyforsensitivegroupsandanAQIabove150Label  matlab.ui.control.Label
        AQIIdentifierLabel            matlab.ui.control.Label
        PleasePresstheLoadDataButtonLampLabel  matlab.ui.control.Label
        PleasePresstheLoadDataButtonLamp  matlab.ui.control.Lamp
        GraphtheNationalPercentGoodModerateBadDaysButton  matlab.ui.control.Button
        SaveDataToatxtDocumentButton  matlab.ui.control.Button
    end


    properties (Access = public)
        Data %Makes a property Data that will be shared throughout the app
        CMedAQI %County Median AQI
        CGoodDays %County Good Days of AQI
        CModDays %County Moderate Days of AQI
        CBadDays %County Bad Days of AQI
        NatAvgMedAQI %National Average Median AQI 
        GoodDays %National Average of Good Days of AQI
        BadDays %National Average of Bad Days of AQI
        ModerateDays %National Average of Moderate Days of AQI
    end

    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadFiles(app, event)
            f = uifigure;%Makes a ui mindow 
            message = sprintf('Please wait.\nData is currently being read and loaded. \nThis window will automatically close once data is finished loading.');
            %Prints for the user to wait for the data to load
            uialert(f,message,'Loading...','Icon','');%Shows loading message
            app.Data=ReadFiles(1998);%Reads the excel files
            close(f);
            
            %activates and deactivates specific buttons so that no errors will occur
            app.LoadDataButton.Enable = 'off';
            app.GetInformationButton.Enable = 'on';
            
            %prints the data has loaded
            app.PleasePresstheLoadDataButtonLampLabel.Text = 'Data has loaded';
            app.PleasePresstheLoadDataButtonLamp.Color = 'g';

            %Makes the title for the app
            app.PleasePresstheLoadDataButtonLampLabel.FontColor = 'g';
            app.UIFigure.Name = 'AQI Identifier';
        end

        % Button pushed function: GetInformationButton
        function GetInfo(app, event)
[row,col]=RowFinder(app.Data,app.EnteraCountyEditField.Value,str2double(app.SelectYearDropDown.Value))
if(row~=0)%tests to see if what the user entered is a valid field and forces the user to reenter a valid field if not valid
    %function calls to get data based on the user input
    [app.CMedAQI, app.CGoodDays, app.CModDays, app.CBadDays] = CountyAverages(app.Data,str2double(app.SelectYearDropDown.Value),app.EnteraCountyEditField.Value);
    CellDisplayer(app, event);
    app.NatAvgMedAQI = FindMedAQI(app.Data,str2double(app.SelectYearDropDown.Value));
    [app.GoodDays, app.ModerateDays, app.BadDays] = AvgDayFinder(app.Data,str2double(app.SelectYearDropDown.Value));
    NationalAverages(app, event)
    
    %activates the plot and save buttons
    app.GraphYourSelectedCountysAQIandtheNationalAQIButton.Enable = 'on';
    app.GraphYourChosenCountysPercentGoodModerateBadDaysButton.Enable = 'on';
    app.GraphtheNationalPercentGoodModerateBadDaysButton.Enable = 'on';
    app.SaveDataToatxtDocumentButton.Enable = 'on';
else%Prints an error message if an invalid field is entered and deactivates the users access to the plot and save buttons
    f = app.UIFigure;
    message = sprintf('The county ''%s'' does not exist, please reenter a valid county name.',app.EnteraCountyEditField.Value);
    uialert(f,message,'Error');
    app.GraphYourSelectedCountysAQIandtheNationalAQIButton.Enable = 'off';
    app.GraphYourChosenCountysPercentGoodModerateBadDaysButton.Enable = 'off';
    app.GraphtheNationalPercentGoodModerateBadDaysButton.Enable = 'off';
    app.SaveDataToatxtDocumentButton.Enable = 'off';
    app.UITable.Data={'','','', ''};
    app.UITable2.Data={'','','', ''};

end
        end

        % Cell edit callback: UITable
        function CellDisplayer(app, event)
        %Writes the data to table
        app.UITable.Data={sprintf('%.2f',app.CMedAQI), sprintf('%.2f',app.CGoodDays), sprintf('%.2f',app.CModDays), sprintf('%.2f',app.CBadDays)};
        end

        % Cell edit callback: UITable2
        function NationalAverages(app, event)
        %Writes the data to table
        app.UITable2.Data={sprintf('%.2f',app.NatAvgMedAQI), sprintf('%.2f',app.GoodDays), sprintf('%.2f',app.ModerateDays), sprintf('%.2f',app.BadDays)};
        end

        % Button pushed function: 
        % GraphYourChosenCountysPercentGoodModerateBadDaysButton
        function GMBGrapher(app, event)
            %Plots the county's good moderate and bad datys
            close(figure(1));
            plotter(app.Data,app.EnteraCountyEditField.Value);
        end

        % Button pushed function: 
        % GraphYourSelectedCountysAQIandtheNationalAQIButton
        function AQIGrapher(app, event)
             %Graphs the National vs County data
             close(figure(1));
            PlotterAQI(app.Data,app.EnteraCountyEditField.Value);
        end

        % Button pushed function: 
        % GraphtheNationalPercentGoodModerateBadDaysButton
        function PlotNationalInfo(app, event)
           %Graphs National Data
           close(figure(1));
            plotterNationalAQI(app.Data);
        end

        % Button pushed function: SaveDataToatxtDocumentButton
        function WriteData(app, event)
           %Saves Data into text files and prints a message notifying the user
            write2files(app.NatAvgMedAQI,app.CGoodDays,app.CModDays,app.CBadDays,app.GoodDays,app.ModerateDays,app.BadDays,app.EnteraCountyEditField.Value,str2double(app.SelectYearDropDown.Value),app.Data); 
            f = app.UIFigure;
            message = sprintf('The information has been saved as ''AQI_Info.txt'' in your MatLab folder.');
            uialert(f,message,'File Saved','Icon','');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EnteraCountyLabel = uilabel(app.UIFigure);
            app.EnteraCountyLabel.Text = 'Enter a County:';
            app.EnteraCountyEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Air Quality Index (AQI)'; '% of Good Days'; '% of Moderate Days'; '% of Bad Days'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @CellDisplayer, true);
            app.GraphYourSelectedCountysAQIandtheNationalAQIButton = uibutton(app.UIFigure, 'push');
            app.GraphYourSelectedCountysAQIandtheNationalAQIButton.ButtonPushedFcn = createCallbackFcn(app, @AQIGrapher, true);
            app.GraphYourSelectedCountysAQIandtheNationalAQIButton.Enable = 'off';
            app.GraphYourSelectedCountysAQIandtheNationalAQIButton.Text = 'Graph Your Selected County''s AQI and the National AQI';
            app.GraphYourChosenCountysPercentGoodModerateBadDaysButton = uibutton(app.UIFigure, 'push');
            app.GraphYourChosenCountysPercentGoodModerateBadDaysButton.ButtonPushedFcn = createCallbackFcn(app, @GMBGrapher, true);
            app.GraphYourChosenCountysPercentGoodModerateBadDaysButton.Enable = 'off';
            app.GraphYourChosenCountysPercentGoodModerateBadDaysButton.Text = 'Graph Your Chosen County''s Percent Good, Moderate, & Bad Days';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFiles, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SelectYearLabel = uilabel(app.UIFigure);
            app.SelectYearLabel.Text = 'Select Year:';
            app.SelectYearDropDown = uidropdown(app.UIFigure);
            app.SelectYearDropDown.Items = {'1998', '1999', '2000', '2001', '2002', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018'};
            app.SelectYearDropDown.Value = '2004';
            app.GetInformationButton = uibutton(app.UIFigure, 'push');
            app.GetInformationButton.ButtonPushedFcn = createCallbackFcn(app, @GetInfo, true);
            app.GetInformationButton.Enable = 'off';
            app.GetInformationButton.Text = 'Get Information';
            app.AQIandPercentofGoodDaysModerateDaysandBadDaysbyCountyLabel = uilabel(app.UIFigure);
            app.AQIandPercentofGoodDaysModerateDaysandBadDaysbyCountyLabel.Text = 'AQI and Percent of Good Days, Moderate Days, and Bad Days by County';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Air Quality Index (AQI)'; '% of Good Days'; '% of Moderate Days'; '% of Bad Days'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @NationalAverages, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'National AQI and Percent of Good Days, Moderate Days, and Bad Days';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Note: The higher the AQI value, the greater the level of air pollution and the greater the health concern.';
            app.AnAQIabove100isunhealthyforsensitivegroupsandanAQIabove150Label = uilabel(app.UIFigure);
            app.AnAQIabove100isunhealthyforsensitivegroupsandanAQIabove150Label.Text = 'An AQI above 100 is unhealthy for sensitive groups, and an AQI above 150 is within the danger zone';
            app.AQIIdentifierLabel = uilabel(app.UIFigure);
            app.AQIIdentifierLabel.Enable = 'off';
            app.AQIIdentifierLabel.Text = 'AQI Identifier';
            app.PleasePresstheLoadDataButtonLampLabel = uilabel(app.UIFigure);
            app.PleasePresstheLoadDataButtonLampLabel.Text = 'Please Press the Load Data Button';
            app.PleasePresstheLoadDataButtonLamp = uilamp(app.UIFigure);
            app.GraphtheNationalPercentGoodModerateBadDaysButton = uibutton(app.UIFigure, 'push');
            app.GraphtheNationalPercentGoodModerateBadDaysButton.ButtonPushedFcn = createCallbackFcn(app, @PlotNationalInfo, true);
            app.GraphtheNationalPercentGoodModerateBadDaysButton.Enable = 'off';
            app.GraphtheNationalPercentGoodModerateBadDaysButton.Text = 'Graph the National Percent Good, Moderate, & Bad Days';
            app.SaveDataToatxtDocumentButton = uibutton(app.UIFigure, 'push');
            app.SaveDataToatxtDocumentButton.ButtonPushedFcn = createCallbackFcn(app, @WriteData, true);
            app.SaveDataToatxtDocumentButton.Enable = 'off';
            app.SaveDataToatxtDocumentButton.Text = 'Save Data To a .txt Document';
        end
    end

    methods (Access = public)

        % Construct app
        function app = finalproject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end